classdef FinalProject_GUI_12_1_24 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GuestButton           matlab.ui.control.Button
        HostButton            matlab.ui.control.Button
        DidyouwinLabel        matlab.ui.control.Label
        NextHandButton        matlab.ui.control.Button
        HandCardsLabel_2      matlab.ui.control.Label
        HandCardsLabel        matlab.ui.control.Label
        tablecardsLabel       matlab.ui.control.Label
        TableCardsLabel       matlab.ui.control.Label
        YourHandCardsLabel    matlab.ui.control.Label
        BalanceamntLabel_3    matlab.ui.control.Label
        BalanceamntLabel_2    matlab.ui.control.Label
        BalanceamntLabel      matlab.ui.control.Label
        potamntLabel          matlab.ui.control.Label
        PotLabel              matlab.ui.control.Label
        amntLabel_2           matlab.ui.control.Label
        RaisedAmountLabel_4   matlab.ui.control.Label
        amntLabel             matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        TexasHoldEmLabel      matlab.ui.control.Label
        FoldedCheckBox_3      matlab.ui.control.CheckBox
        CalledCheckBox_3      matlab.ui.control.CheckBox
        Player3Label          matlab.ui.control.Label
        RaisedAmountLabel     matlab.ui.control.Label
        FoldedCheckBox_2      matlab.ui.control.CheckBox
        CalledCheckBox_2      matlab.ui.control.CheckBox
        Player2Label          matlab.ui.control.Label
        AmountEditField       matlab.ui.control.NumericEditField
        AmountEditFieldLabel  matlab.ui.control.Label
        RaiseButton           matlab.ui.control.Button
        CallButton            matlab.ui.control.Button
        FoldButton            matlab.ui.control.Button
    end

    %variables to keep track of each players winnings and the pot
    properties (Access = private)
        p1Bal double = 100;
        p2Bal double = 100;
        p3Bal double = 100;
        potBal double = 100;
        
        p1Cards = [0,0;0,0];
        p2Cards = [0;0]; % ignore
        p3Cards = [0,0;0,0];
        tableCards = ["0", "0", "0", "0", "0"; "S", "S", "S", "S", "S"];
        
        roundNum = 0;
        channelID = 2776676;
        writeKey = "CYR8YQRIZD2BA8P8";
        readKey = "JH3LIBW6JC1LS6SS";

        isHost = 0;
        isGuest = 0;
        % -1 = fold, 0 = calling, > 0 = raise, -2 = default
        p1action = -2;
    end

    methods (Access = private)
        % Updates the labels for balances and the pot dynamically
        function updateLabels(app)
            % Update the pot label
            app.potamntLabel.Text = sprintf('$ %.2f', app.potBal);
            
            % Update each player's balance labels
            app.BalanceamntLabel.Text = sprintf('$ Balance: %.2f', app.p1Bal);
            app.BalanceamntLabel_2.Text = sprintf('$ Balance: %.2f', app.p2Bal);
            app.BalanceamntLabel_3.Text = sprintf('$ Balance: %.2f', app.p3Bal);

            % Print card values to screen
            app.YourHandCardsLabel.Text = "Your Hand: " + app.p1Cards(1,1) + app.p1Cards(2,1) + " " + app.p1Cards(1,2) + app.p1Cards(2,2);
            tmp = app.tableCards;
            tmp(3, :) = " ";
            disp(app.tableCards)
            cardString = strjoin(tmp(1:end - 1)');
            app.tablecardsLabel.Text = cardString;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InitVal(app)
            % Wait until decided if guest or host
            while ~xor(app.isGuest, app.isHost)
                pause(1);
            end
            if app.isHost
                % Initialize balances and pot
                app.p1Bal = 1000; % Set initial balance for Player 1
                app.p2Bal = 1000; % Set initial balance for Player 2
                app.p3Bal = 1000; % Set initial balance for Player 3
                app.potBal = 0;   % Set initial pot values
                % Update the labels to reflect initial values
                updateLabels(app);
                % Initialize card values
                %there should be a function here to read the card values
    
                deck = Deck();
                deck = deck.shuffled();
                
                % Deal out 2 cards             per player
                % Player 1
                [app.p1Cards, deck] = deck.dealn(2);
                disp(app.p1Cards);
    
                % Player 2
                [app.p2Cards, deck] = deck.dealn(2);
                disp(app.p2Cards);
    
                % Player 3
                [app.p3Cards, deck] = deck.dealn(2);
                disp(app.p3Cards);
                
                updateLabels(app);
                
                p2action = -2;
                %p2action = ThingSpeakRead... % fetch p2 action
    
                while (app.p1action == -2 || p2action == -2)
                    %p2action = ThingSpeakRead... % fetch p2 action
                end
                % Clear thingspeak channel of action
    
                % deal with actions
    
                % Deal center cards
    
                [app.tableCards, deck] = deck.dealn(3);
                disp(app.tableCards);
                
                while (app.p1action == -2 || p2action == -2)
                    %p2action = ThingSpeakRead... % fetch p2 action
                end
    
                % deal with actions
    
                % eval cards and decide winner
                
                % upadte p2winner on thingspeak
            else
                % handle player 2
                % push that player 2 is ready
                % fetch cards and load them in
                
            end


            % Evaluate test
            %{


            player1EvalArray = [app.p1Cards, app.tableCards];
            player1Eval = Eval.eval7(player1EvalArray);
            player2EvalArray = [app.p2Cards, app.tableCards];
            player2Eval = Eval.eval7(player2EvalArray);
            
            % Compare and decide winner
            didYouWin = player1Eval > player2Eval;
            app.DidyouwinLabel.Text = "Did you win?        " + didYouWin;
            %}

        end

        % Button pushed function: FoldButton
        function FoldButtonPushed(app, event)
            %this should end the round for the player
            % Push to p2_won
        end

        % Button pushed function: CallButton
        function CallButtonPushed(app, event)
            callAmount = app.AmountEditField.Value; % Example call amount
            currentPlayerBalance = app.p1Bal; % Example for Player 1 (can be dynamic)
        
            if currentPlayerBalance >= callAmount
                app.p1Bal = app.p1Bal - callAmount; % Deduct call amount from balance
                app.potBal = app.potBal + callAmount; % Add to the pot
                updateLabels(app); % Update labels
            else
                uialert(app.UIFigure, 'Insufficient balance to call.', 'Error'); % Display error
            end
        end

        % Button pushed function: RaiseButton
        function RaiseButtonPushed(app, event)
            raiseAmount = app.AmountEditField.Value; % Get raise amount
            currentPlayerBalance = app.p1Bal; % Example for Player 1 (can be dynamic)
        
            if currentPlayerBalance >= raiseAmount && raiseAmount >=0
                app.p1Bal = app.p1Bal - raiseAmount; % Deduct from player's balance
                app.potBal = app.potBal + raiseAmount; % Add to the pot
                updateLabels(app); % Update labels
            else
                uialert(app.UIFigure, 'Insufficient balance to raise.', 'Error'); % Display error
            end
        end

        % Button pushed function: NextHandButton
        function NextHandButtonPushed(app, event)
            %need function to start the next hand
            %has to keep everyones balance but empty the pot
            %and deal new cards
        end

        % Button pushed function: HostButton
        function HostButtonPushed(app, event)
            app.isHost = 1;
            app.HostButton.BackgroundColor = [1, 0, 0];
        end

        % Button pushed function: GuestButton
        function GuestButtonPushed(app, event)
            app.isGuest = 1;
            app.GuestButton.BackgroundColor = [1, 0, 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FoldButton = uibutton(app.UIFigure, 'push');
            app.FoldButton.ButtonPushedFcn = createCallbackFcn(app, @FoldButtonPushed, true);
            app.FoldButton.Text = 'Fold';
            app.CallButton = uibutton(app.UIFigure, 'push');
            app.CallButton.ButtonPushedFcn = createCallbackFcn(app, @CallButtonPushed, true);
            app.CallButton.Text = 'Call';
            app.RaiseButton = uibutton(app.UIFigure, 'push');
            app.RaiseButton.ButtonPushedFcn = createCallbackFcn(app, @RaiseButtonPushed, true);
            app.RaiseButton.Text = 'Raise';
            app.AmountEditFieldLabel = uilabel(app.UIFigure);
            app.AmountEditFieldLabel.Text = '$ Amount';
            app.AmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmountEditField.Value = 352;
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.CalledCheckBox_2 = uicheckbox(app.UIFigure);
            app.CalledCheckBox_2.Text = 'Called';
            app.FoldedCheckBox_2 = uicheckbox(app.UIFigure);
            app.FoldedCheckBox_2.Text = 'Folded';
            app.RaisedAmountLabel = uilabel(app.UIFigure);
            app.RaisedAmountLabel.Text = '$ Raised  Amount:';
            app.Player3Label = uilabel(app.UIFigure);
            app.Player3Label.Text = 'Player 3';
            app.CalledCheckBox_3 = uicheckbox(app.UIFigure);
            app.CalledCheckBox_3.Text = 'Called';
            app.FoldedCheckBox_3 = uicheckbox(app.UIFigure);
            app.FoldedCheckBox_3.Text = 'Folded';
            app.TexasHoldEmLabel = uilabel(app.UIFigure);
            app.TexasHoldEmLabel.Text = 'Texas Hold ''Em';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '360_F_338379003_37giLQjJMMI3uFS2EFAQkzHr7QyaCS26-removebg-preview.png');
            app.amntLabel = uilabel(app.UIFigure);
            app.amntLabel.Text = '{amnt}';
            app.RaisedAmountLabel_4 = uilabel(app.UIFigure);
            app.RaisedAmountLabel_4.Text = '$ Raised  Amount:';
            app.amntLabel_2 = uilabel(app.UIFigure);
            app.amntLabel_2.Text = '{amnt}';
            app.PotLabel = uilabel(app.UIFigure);
            app.PotLabel.Text = '$ Pot:';
            app.potamntLabel = uilabel(app.UIFigure);
            app.potamntLabel.Text = '{pot amnt}';
            app.BalanceamntLabel = uilabel(app.UIFigure);
            app.BalanceamntLabel.Text = '$ Balance: {amnt}';
            app.BalanceamntLabel_2 = uilabel(app.UIFigure);
            app.BalanceamntLabel_2.Text = '$ Balance: {amnt}';
            app.BalanceamntLabel_3 = uilabel(app.UIFigure);
            app.BalanceamntLabel_3.Text = '$ Balance: {amnt}';
            app.YourHandCardsLabel = uilabel(app.UIFigure);
            app.YourHandCardsLabel.Text = 'Your Hand: {Cards}';
            app.TableCardsLabel = uilabel(app.UIFigure);
            app.TableCardsLabel.Text = 'Table Cards:';
            app.tablecardsLabel = uilabel(app.UIFigure);
            app.tablecardsLabel.Text = '{table cards}';
            app.HandCardsLabel = uilabel(app.UIFigure);
            app.HandCardsLabel.Text = 'Hand: {Cards}';
            app.HandCardsLabel_2 = uilabel(app.UIFigure);
            app.HandCardsLabel_2.Text = 'Hand: {Cards}';
            app.NextHandButton = uibutton(app.UIFigure, 'push');
            app.NextHandButton.ButtonPushedFcn = createCallbackFcn(app, @NextHandButtonPushed, true);
            app.NextHandButton.Text = 'Next Hand';
            app.DidyouwinLabel = uilabel(app.UIFigure);
            app.DidyouwinLabel.Text = 'Did you win?';
            app.HostButton = uibutton(app.UIFigure, 'push');
            app.HostButton.ButtonPushedFcn = createCallbackFcn(app, @HostButtonPushed, true);
            app.HostButton.Text = 'Host';
            app.GuestButton = uibutton(app.UIFigure, 'push');
            app.GuestButton.ButtonPushedFcn = createCallbackFcn(app, @GuestButtonPushed, true);
            app.GuestButton.Text = 'Guest';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProject_GUI_12_1_24

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @InitVal)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end